classdef ShatteringApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FilenameEditFieldLabel        matlab.ui.control.Label
        FilenameEditField             matlab.ui.control.EditField
        LoadLSMButton                 matlab.ui.control.Button
        TimepointEditFieldLabel       matlab.ui.control.Label
        TimepointEditField            matlab.ui.control.NumericEditField
        MaxProjectButton              matlab.ui.control.Button
        FirstZLabel                   matlab.ui.control.Label
        FirstZEditField               matlab.ui.control.NumericEditField
        StackSizeEditFieldLabel       matlab.ui.control.Label
        StackSizeEditField            matlab.ui.control.NumericEditField
        UITable                       matlab.ui.control.Table
        CannyThresholdEditFieldLabel  matlab.ui.control.Label
        CannyThresholdEditField       matlab.ui.control.NumericEditField
        DilationwidthEditFieldLabel   matlab.ui.control.Label
        DilationwidthEditField        matlab.ui.control.NumericEditField
        SegmentButton                 matlab.ui.control.Button
        DilateButton                  matlab.ui.control.Button
        AnalyzeimageButton            matlab.ui.control.Button
        ExporttoExcelCheckBox         matlab.ui.control.CheckBox
        RemoveobjectsCheckBox         matlab.ui.control.CheckBox
        LessthanEditFieldLabel        matlab.ui.control.Label
        LessthanEditField             matlab.ui.control.NumericEditField
        CloseAllButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        FullStack % Description
        SubStack % Description
        DataSize
        MaxProjection % Description
        SegmentedImage % Description
        DilatedImage % Description
    end
    
    methods (Access = private)
        
        function loadLSM(app)
            filename = app.FilenameEditField.Value;
            [app.FullStack LSMinfo] = app.lsmread(filename);
            app.FullStack = permute(app.FullStack, [1 2 4 5 3]);
            app.DataSize = size(app.FullStack);
            app.UITable.Data = {app.DataSize(1),app.DataSize(3),app.DataSize(4),app.DataSize(5)};
        end
        
        function [Data, varargout] = lsmread(~,fileName, varargin)
            %LSMREAD returns information and image data of a ZEISS lsm file.
            % [Data] = lsmread(filename) will return image data in its original
            % dimensions, organized as [T(ime), C(hannel), Z, X, Y]
            % [Data] = lsmread(filename,'Range',[T0 T1 C0 C1 Z0 Z1]) will return image data with specified dimension.
            % [Data LSMinfo] = lsmread(filename) will return image data as well as LSMinfo
            % [LSMinfo] = lsmread(filename,'InfoOnly') will only return LSM infos.
            %---------------------------------------------------------------------------------
            % Notes for the Zeiss LSM format.
            % The first 8 bytes are TIFF file header.
            % Each 12-byte IFD entry has the following format: Bytes 0-1: The Tag that
            % identifies the field. Byte 2-3: The field type. Bytes 4-7: Count.
            % Bytes 8-11: Value or offset.Byte count of the indicated types.
            % Types 1 = BYTE, 2 =ASCII (8-bit),3= SHORT (16-bit), 4= LONG(32-bit).
            % If the count is 1, then byes 8-11 stores the value, otherwise it's offset
            % pointing to the position in file where value is stored.
            % Each optical section at every time point (no matter how many channels)
            % will have two image file directories(IFDs). The first one contains
            % information for the real image data. The second IFD contains
            % information for thumbnail images. The LSM file is structured as such :
            % header -> IFDs -> (real image/thumbnail)s.
            % The very first IFD has an entry numbered 34412 and points to LSM-specific data.
            %----------------------------------------------------------------------------------
            % Version 1.7
            % Copyright Chao-Yuan Yeh, 2016.
            % The script is inspired by LSM File Toolbox by Peter Li and tiffrd by Francois Nedelec.
            
            f = waitbar(0,'Initializing ...');
            
            fID = fopen(fileName);
            byteOrder = fread(fID,2,'*char')';
            if (strcmp(byteOrder,'II'))
                byteOrder = 'ieee-le';
            elseif (strcmp(byteOrder,'MM'))
                byteOrder = 'ieee-be';
            else
                error('This is not a correct TIFF file');
            end
            
            tiffID = fread(fID, 1, 'uint16', byteOrder);
            if (tiffID ~= 42)
                error('This is not a correct TIFF file');
            end
            
            fseek(fID, 4, 'bof');
            ifdPos = fread(fID, 1, 'uint32', byteOrder);
            fseek(fID, ifdPos, 'bof');
            
            IFDIdx = 0;
            while ifdPos ~= 0
                IFDIdx = IFDIdx+1;
                fseek(fID,ifdPos, 'bof');
                % The first two bytes of each IFD specify number of IFD entries.
                numEntries = fread(fID,1,'uint16',byteOrder);
                entryPos = ifdPos+2;
                % Each IFD entry is 12-byte long.
                fseek(fID, ifdPos+12*numEntries+2, 'bof');
                % The last four bytes of an IFD specifies offset to next IFD.
                % If this is zero, it means there's no other IFDs.
                ifdPos = fread(fID, 1, 'uint32', byteOrder);
                % IFD is structured like this: bytes 1-2 : tag, bytes 3-4: type,
                % bytes 5-8: count, bytes 9-12: value/offset
                for ii = 1:numEntries
                    fseek(fID, entryPos+12*(ii-1), 'bof');
                    IFD{IFDIdx}(1,ii) = fread(fID, 1, 'uint16', byteOrder);
                    IFD{IFDIdx}(2,ii) = fread(fID, 1, 'uint16', byteOrder);
                    IFD{IFDIdx}(3,ii) = fread(fID, 1, 'uint32', byteOrder);
                    IFD{IFDIdx}(4,ii) = fread(fID, 1, 'uint32', byteOrder);
                end
            end
            
            %Reading LSMinfo
            if IFD{1}(3, IFD{1}(1, :) == 258) == 1
                LSMinfo.bitDepth = IFD{1}(4, IFD{1}(1, :) == 258);
            else
                fseek(fID, IFD{1}(4,IFD{1}(1, :) == 258),'bof');
                LSMinfo.bitDepth = fread(fID, 1, 'uint16', byteOrder);
            end
            
            offsetLSMinfo = IFD{1}(4,IFD{1}(1, :) == 34412)+8;
            fseek(fID, offsetLSMinfo, 'bof');
            LSMinfo.dimX = fread(fID, 1, 'uint32', byteOrder);
            LSMinfo.dimY = fread(fID, 1, 'uint32', byteOrder);
            LSMinfo.dimZ = fread(fID, 1, 'uint32', byteOrder);
            LSMinfo.dimC = fread(fID, 1, 'uint32', byteOrder);
            LSMinfo.dimT = fread(fID, 1, 'uint32', byteOrder);
            fseek(fID, 12, 'cof');
            LSMinfo.voxSizeX = fread(fID, 1, 'float64', byteOrder);
            LSMinfo.voxSizeY = fread(fID, 1, 'float64', byteOrder);
            LSMinfo.voxSizeZ = fread(fID, 1, 'float64', byteOrder);
            fseek(fID, 26, 'cof');
            LSMinfo.specScan = fread(fID, 1, 'uint16', byteOrder);
            
            if any(strcmpi(varargin, 'InfoOnly'))
                Data = LSMinfo;
                fclose(fID);
            else
                % Creating offset list for every IFD
                offset = zeros(1, IFDIdx/2);
                for ii= 1 : IFDIdx/2
                    if IFD{2*ii - 1}(3,7) == 1 && IFD{2 * ii - 1}(4, 7) < 4294967296
                        offset(ii) = IFD{2 * ii - 1}(4, 7);
                    else
                        fseek(fID, IFD{2 * ii - 1}(4, 7), 'bof');
                        offset(ii) = fread(fID, 1, 'uint32', byteOrder);
                    end
                end
                
                if any(strcmpi(varargin, 'Range'))
                    Range = varargin{find(strcmpi(varargin, 'Range')) + 1};
                    if any([Range(1) < 1, Range(2) > LSMinfo.dimT, Range(3) < 1, ...
                            Range(4) > LSMinfo.dimC, Range(5) < 1, ...
                            Range(6)>LSMinfo.dimZ])
                        error('Input range exceeds data range');
                    else
                        dimT0 = Range(1);
                        dimT1 = Range(2);
                        dimC0 = Range(3);
                        dimC1 = Range(4);
                        dimZ0 = Range(5);
                        dimZ1 = Range(6);
                    end
                else
                    dimT0 = 1; dimT1 = LSMinfo.dimT;
                    dimZ0 = 1; dimZ1 = LSMinfo.dimZ;
                    dimC0 = 1; dimC1 = LSMinfo.dimC;
                end
                
                bitDepth = strcat('uint',num2str(LSMinfo.bitDepth));
                Data = zeros(dimT1-dimT0+1, dimC1-dimC0+1, dimZ1-dimZ0+1, ...
                    LSMinfo.dimY, LSMinfo.dimX, bitDepth);
                
                waitbar ( 0,f,'Loading images...');
                
                for indT = dimT0 : dimT1
                    waitbar ( indT/dimT1,f,['Loading time ' num2str(indT) ' out of ' num2str(dimT1)]);
                    
                    for indZ = dimZ0 : dimZ1
                        fseek(fID, offset((indT-1) * LSMinfo.dimZ + indZ),'bof');
                        switch bitDepth
                            case 'uint16'
                                fseek(fID, (dimC0-1)*LSMinfo.dimX*LSMinfo.dimY*2, 'cof');
                                for indC = dimC0 : dimC1
                                    Data(indT-dimT0+1, indC-dimC0+1, indZ-dimZ0+1, :, :) ...
                                        = reshape(uint16(...
                                        fread(fID, LSMinfo.dimX * LSMinfo.dimY, ...
                                        bitDepth, byteOrder)), LSMinfo.dimX, LSMinfo.dimY)';
                                end
                            case 'uint8'
                                fseek(fID, (dimC0-1)*LSMinfo.dimX*LSMinfo.dimY, 'cof');
                                for indC = dimC0 : dimC1
                                    Data(indT-dimT0+1,indC-dimC0+1,indZ-dimZ0+1,:,:) ...
                                        = reshape(uint8(...
                                        fread(fID,LSMinfo.dimX*LSMinfo.dimY, ...
                                        bitDepth, byteOrder)), LSMinfo.dimX, LSMinfo.dimY)';
                                end
                        end
                    end
                end
                close(f)
                
                fclose(fID);
                if nargout==2
                    varargout{1} = LSMinfo;
                end
            end
        end
        
        
        function cannySegment(app,fig)
            
            [~, threshold] = edge(app.MaxProjection, 'sobel');
            app.SegmentedImage=edge(app.MaxProjection,'canny', app.CannyThresholdEditField.Value*threshold);
            
            if fig
                figure, imshow(app.SegmentedImage)
            end
            
        end
        
        function dilateImage(app,fig)
            
            cannySegment(app,0);
            se90 = strel('line', app.DilationwidthEditField.Value, 90);
            se0 = strel('line', app.DilationwidthEditField.Value, 0);
            app.DilatedImage = imdilate(app.SegmentedImage, [se90 se0]);
            
            if fig
                figure, imshow(app.DilatedImage), title('dilated gradient mask');
            end
            
        end
        
    end

    methods (Access = private)

        % Button pushed function: LoadLSMButton
        function LoadLSMButtonPushed(app, event)
            
            if isempty(app.FilenameEditField.Value)
                [file path] = uigetfile('*.lsm');
                app.FilenameEditField.Value = [path file];
            end
            loadLSM(app);
        end

        % Button pushed function: MaxProjectButton
        function MaxProjectButtonPushed(app, event)
            timepoint = app.TimepointEditField.Value;
            firstz = app.FirstZEditField.Value;
            stacksize = app.StackSizeEditField.Value;
            
            if timepoint <= app.DataSize(1)
                
                
                if firstz <= app.DataSize(5)
                    
                    if (firstz + stacksize-1)<=app.DataSize(5)
                        app.SubStack = app.FullStack(timepoint,1,:,:,firstz:(firstz+stacksize-1));
                        app.SubStack = squeeze(app.SubStack);
                        app.MaxProjection = max(app.SubStack,[],3);
                        imagesc(app.MaxProjection);
                        
                    else
                        f = warndlg('Specified stack size exceeds boundaries');
                    end
                else
                    
                    f = warndlg('Specified initial Z exceeds boundaries');
                end
            else
                f = warndlg('Specified time point exceeds boundaries');
            end
            
            
        end

        % Button pushed function: SegmentButton
        function SegmentButtonPushed(app, event)
            app.cannySegment(1);
            
        end

        % Button pushed function: AnalyzeimageButton
        function AnalyzeimageButtonPushed(app, event)
            app.dilateImage(0);
            
            
            BWdfill = imfill(app.DilatedImage, 'holes');
            
            stats = regionprops('table',BWdfill,'Centroid','MajorAxisLength','MinorAxisLength','Area','Perimeter');
            centers = stats.Centroid;

            
            BWoutline = bwperim(BWdfill);
            Segout = app.MaxProjection;
            Segout(BWoutline) = 255;
            figure, imshow(Segout), title('outlined original image');
            
            if app.ExporttoExcelCheckBox.Value
               
                [file, path] = uiputfile('*.xlsx');
                filename = [path file];
                
                stats = sortrows(stats,1);
                
               if app.RemoveobjectsCheckBox.Value
                  
                   toDelete = stats.Area<app.LessthanEditField.Value;
                   stats(toDelete,:) = [];
                   
               end
                
                writetable(stats,filename);
                
            end
            
            
        end

        % Button pushed function: DilateButton
        function DilateButtonPushed(app, event)
            app.dilateImage(1);
        end

        % Button pushed function: CloseAllButton
        function CloseAllButtonPushed(app, event)
            close all
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.FilenameEditField.Value = 'D:\Working Data\AngelProgram\CCL19-2.lsm';
            app.LoadLSMButton = uibutton(app.UIFigure, 'push');
            app.LoadLSMButton.ButtonPushedFcn = createCallbackFcn(app, @LoadLSMButtonPushed, true);
            app.LoadLSMButton.Text = 'Load LSM';
            app.TimepointEditFieldLabel = uilabel(app.UIFigure);
            app.TimepointEditFieldLabel.Text = 'Timepoint';
            app.TimepointEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimepointEditField.Value = 1;
            app.MaxProjectButton = uibutton(app.UIFigure, 'push');
            app.MaxProjectButton.ButtonPushedFcn = createCallbackFcn(app, @MaxProjectButtonPushed, true);
            app.MaxProjectButton.Text = 'Max Project';
            app.FirstZLabel = uilabel(app.UIFigure);
            app.FirstZLabel.Text = 'First Z';
            app.FirstZEditField = uieditfield(app.UIFigure, 'numeric');
            app.FirstZEditField.Value = 75;
            app.StackSizeEditFieldLabel = uilabel(app.UIFigure);
            app.StackSizeEditFieldLabel.Text = 'Stack Size';
            app.StackSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.StackSizeEditField.Value = 10;
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Timepoints'; 'X'; 'Y'; 'Z'};
            app.UITable.RowName = {};
            app.CannyThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.CannyThresholdEditFieldLabel.Text = 'Canny Threshold';
            app.CannyThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.CannyThresholdEditField.Value = 1.25;
            app.DilationwidthEditFieldLabel = uilabel(app.UIFigure);
            app.DilationwidthEditFieldLabel.Text = 'Dilation width';
            app.DilationwidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.DilationwidthEditField.Value = 3;
            app.SegmentButton = uibutton(app.UIFigure, 'push');
            app.SegmentButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentButtonPushed, true);
            app.SegmentButton.Text = 'Segment';
            app.DilateButton = uibutton(app.UIFigure, 'push');
            app.DilateButton.ButtonPushedFcn = createCallbackFcn(app, @DilateButtonPushed, true);
            app.DilateButton.Text = 'Dilate';
            app.AnalyzeimageButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeimageButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeimageButtonPushed, true);
            app.AnalyzeimageButton.Text = 'Analyze image';
            app.ExporttoExcelCheckBox = uicheckbox(app.UIFigure);
            app.ExporttoExcelCheckBox.Text = 'Export to Excel?';
            app.RemoveobjectsCheckBox = uicheckbox(app.UIFigure);
            app.RemoveobjectsCheckBox.Text = 'Remove objects';
            app.LessthanEditFieldLabel = uilabel(app.UIFigure);
            app.LessthanEditFieldLabel.Text = 'Less than';
            app.LessthanEditField = uieditfield(app.UIFigure, 'numeric');
            app.CloseAllButton = uibutton(app.UIFigure, 'push');
            app.CloseAllButton.ButtonPushedFcn = createCallbackFcn(app, @CloseAllButtonPushed, true);
            app.CloseAllButton.Text = 'Close All';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ShatteringApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end